classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        INFOButton                      matlab.ui.control.Button
        ShowplotsCheckBox               matlab.ui.control.CheckBox
        RESULTSPanel                    matlab.ui.container.Panel
        EditField_3Label_4              matlab.ui.control.Label
        FScoreEditField_STD             matlab.ui.control.NumericEditField
        FScoreEditField                 matlab.ui.control.NumericEditField
        FScoreEditFieldLabel            matlab.ui.control.Label
        EditField_3Label_3              matlab.ui.control.Label
        MathewscoefficientEditField_STD  matlab.ui.control.NumericEditField
        MathewscoefficientEditField     matlab.ui.control.NumericEditField
        MathewscoefficientEditFieldLabel  matlab.ui.control.Label
        EditField_3Label_2              matlab.ui.control.Label
        SpecificityEditField_STD        matlab.ui.control.NumericEditField
        SpecificityEditField            matlab.ui.control.NumericEditField
        SpecificityEditFieldLabel       matlab.ui.control.Label
        EditField_3Label                matlab.ui.control.Label
        SensitivityEditField_STD        matlab.ui.control.NumericEditField
        SensitivityEditField            matlab.ui.control.NumericEditField
        SensivityEditFieldLabel         matlab.ui.control.Label
        AccuracyEditField_STD           matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        AccuracyEditField               matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel          matlab.ui.control.Label
        TESTButton                      matlab.ui.control.Button
        ClassifierEvaluationPanel       matlab.ui.container.Panel
        ParametersPanel_4               matlab.ui.container.Panel
        KSpinner_4                      matlab.ui.control.Spinner
        KSpinner_4Label                 matlab.ui.control.Label
        TrainingPercentageSpinner       matlab.ui.control.Spinner
        TrainingPercentageSpinnerLabel  matlab.ui.control.Label
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        HoldoutButton_2                 matlab.ui.control.RadioButton
        CrossValidationButton_2         matlab.ui.control.RadioButton
        ClassifierPanel                 matlab.ui.container.Panel
        ParametersPanel                 matlab.ui.container.Panel
        GammaSpinner                    matlab.ui.control.Spinner
        GammaSpinnerLabel               matlab.ui.control.Label
        KSpinner                        matlab.ui.control.Spinner
        KSpinnerLabel                   matlab.ui.control.Label
        CostSpinner                     matlab.ui.control.Spinner
        CostSpinnerLabel                matlab.ui.control.Label
        FindappropriateparametersCheckBox  matlab.ui.control.CheckBox
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        KNNButton                       matlab.ui.control.RadioButton
        BayesButton                     matlab.ui.control.RadioButton
        SVMRBFButton                    matlab.ui.control.RadioButton
        SVMLinearButton                 matlab.ui.control.RadioButton
        FisherLDAButton                 matlab.ui.control.RadioButton
        MDCMahalanobisButton            matlab.ui.control.RadioButton
        MDCEuclideanButton              matlab.ui.control.RadioButton
        FeatureReductionPanel           matlab.ui.container.Panel
        PlotReduceddataCheckBox         matlab.ui.control.CheckBox
        PlotParetoCheckBox              matlab.ui.control.CheckBox
        PlotEigenvaluesCheckBox         matlab.ui.control.CheckBox
        ParametersPanel_3               matlab.ui.container.Panel
        KaiserCriterionCheckBox         matlab.ui.control.CheckBox
        NoofdimensionsSpinner           matlab.ui.control.Spinner
        NoofdimensionsSpinnerLabel      matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        LDAButton                       matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        FeatureSelectionPanel           matlab.ui.container.Panel
        ShowheatmapCheckBox             matlab.ui.control.CheckBox
        ParametersPanel_2               matlab.ui.container.Panel
        CorrelationThresholdSpinner     matlab.ui.control.Spinner
        CorrelationThresholdLabel       matlab.ui.control.Label
        NooffeaturesSpinner             matlab.ui.control.Spinner
        NooffeaturesSpinnerLabel        matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        KruskalWallisCorrelationButton  matlab.ui.control.RadioButton
        CorrelationButton               matlab.ui.control.RadioButton
        KruskalWallisButton             matlab.ui.control.RadioButton
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PatternRecognition202021JoanaBaioJooFerreiraLabel  matlab.ui.control.Label
        Covid19predictionLabel          matlab.ui.control.Label
        Label_blue                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        
        scenario = 'A';
        
        % Defaults - Feature Selection
        selection_method = "Kruskal-Wallis";
        show_heatmap = 0;
        n_features = 0;
        corr_threshold = 0;
        
        % Defaults - Feature Reduction
        reduction_method = "PCA";
        dim = 0;
        kaiser_criterion = 0;
        show_pareto = 0;
        show_eigenvalues = 0;
        show_reduced_data = 0;
        
        % Defaults - Classifier
        classifier_method = "MDC - Euclidean"
        k = 0;
        cost = 0;
        gamma = 0;
        find_parameters = 0;
        
        % Defaults - Classification
        test_option = "Cross-Validation";
        k_evaluation = 0;
        training_percentage = 0;
        
        % Test
        show_plots = 0;
        
        % Results
        accuracy = 0;
        accuracy_std = 0;
        sensitivity = 0;
        sensitivity_std = 0;
        specificity = 0;
        specificity_std = 0;
        mathews_coef = 0;
        mathews_coef_std = 0;
        fscore = 0;
        fscore_std = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            value = app.ScenarioDropDown.Value;
            app.scenario = value;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            selectedButton = app.ButtonGroup.SelectedObject;
            app.selection_method = selectedButton.Text;

            if selectedButton == app.KruskalWallisButton  
                set(app.NooffeaturesSpinner, 'Enable', 'on');
                set(app.NooffeaturesSpinner, 'Enable', 'on');
                set(app.CorrelationThresholdSpinner, 'Enable', 'off');
                set(app.ShowheatmapCheckBox, 'Enable', 'off');

            elseif selectedButton == app.CorrelationButton
                set(app.NooffeaturesSpinner, 'Enable', 'off');
                set(app.CorrelationThresholdSpinner, 'Enable', 'on');
                set(app.ShowheatmapCheckBox, 'Enable', 'on');
            
            elseif selectedButton == app.KruskalWallisCorrelationButton
                
                set(app.NooffeaturesSpinner, 'Enable', 'on');
                set(app.CorrelationThresholdSpinner, 'Enable', 'on');
                set(app.ShowheatmapCheckBox, 'Enable', 'on');
            end
        end

        % Value changed function: ShowheatmapCheckBox
        function ShowheatmapCheckBoxValueChanged(app, event)
            value = app.ShowheatmapCheckBox.Value;
            app.show_heatmap = value;
        end

        % Value changed function: NooffeaturesSpinner
        function NooffeaturesSpinnerValueChanged(app, event)
            value = app.NooffeaturesSpinner.Value;
            app.n_features = value;
        end

        % Value changed function: CorrelationThresholdSpinner
        function CorrelationThresholdSpinnerValueChanged(app, event)
            value = app.CorrelationThresholdSpinner.Value;
            app.corr_threshold = value;
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            app.reduction_method = selectedButton.Text;
            
            if selectedButton == app.PCAButton   

                set(app.PlotEigenvaluesCheckBox, 'Enable', 'on');
                set(app.PlotParetoCheckBox, 'Enable', 'on');
                set(app.PlotReduceddataCheckBox, 'Enable', 'on');
                set(app.KaiserCriterionCheckBox, 'Enable', 'on');
                if (app.kaiser_criterion == 0)
                    set(app.NoofdimensionsSpinner, 'Enable', 'on');
                end


            elseif selectedButton == app.LDAButton
                
                set(app.PlotEigenvaluesCheckBox, 'Enable', 'off');
                set(app.PlotParetoCheckBox, 'Enable', 'off');
                set(app.PlotReduceddataCheckBox, 'Enable', 'on');
                set(app.KaiserCriterionCheckBox, 'Enable', 'off');            
                if (app.kaiser_criterion == 0)
                    set(app.NoofdimensionsSpinner, 'Enable', 'off');
                end
            end
        end

        % Value changed function: KaiserCriterionCheckBox
        function KaiserCriterionCheckBoxValueChanged(app, event)
            value = app.KaiserCriterionCheckBox.Value;
            app.kaiser_criterion = value;
            
            if (value == 1)
                set(app.NoofdimensionsSpinner, 'Enable', 'off');
                app.dim = 0;
            elseif (value == 0)
                set(app.NoofdimensionsSpinner, 'Enable', 'on');
            end
        end

        % Value changed function: NoofdimensionsSpinner
        function NoofdimensionsSpinnerValueChanged(app, event)
            value = app.NoofdimensionsSpinner.Value;
            app.dim = value;
        end

        % Value changed function: PlotEigenvaluesCheckBox
        function PlotEigenvaluesCheckBoxValueChanged(app, event)
            value = app.PlotEigenvaluesCheckBox.Value;
            app.show_eigenvalues = value;
        end

        % Value changed function: PlotParetoCheckBox
        function PlotParetoCheckBoxValueChanged(app, event)
            value = app.PlotParetoCheckBox.Value;
            app.show_pareto = value;
        end

        % Value changed function: PlotReduceddataCheckBox
        function PlotReduceddataCheckBoxValueChanged(app, event)
            value = app.PlotReduceddataCheckBox.Value;
            app.show_reduced_data = value;
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            app.classifier_method = selectedButton.Text;
            parameters = [app.FindappropriateparametersCheckBox, app.CostSpinner, app.KSpinner, app.GammaSpinner];
            set(parameters, 'Enable', 'off');
            if (selectedButton == app.MDCEuclideanButton || selectedButton == app.MDCMahalanobisButton || selectedButton == app.FisherLDAButton || selectedButton == app.BayesButton)
                set(parameters, 'Enable', 'off');
            elseif (selectedButton == app.SVMLinearButton)
                set(app.FindappropriateparametersCheckBox, 'Enable', 'on');
                if app.find_parameters == 0
                    set(app.CostSpinner, 'Enable', 'on');
                end
            elseif (selectedButton == app.SVMRBFButton)
                set(app.FindappropriateparametersCheckBox, 'Enable', 'on');
                if app.find_parameters == 0
                    set(app.CostSpinner, 'Enable', 'on');
                    set(app.GammaSpinner, 'Enable', 'on');
                end
            elseif (selectedButton == app.KNNButton)
                set(app.FindappropriateparametersCheckBox, 'Enable', 'on');
                if app.find_parameters == 0
                    set(app.KSpinner, 'Enable', 'on')
                end
            end
            
        end

        % Value changed function: FindappropriateparametersCheckBox
        function FindappropriateparametersCheckBoxValueChanged(app, event)
            value = app.FindappropriateparametersCheckBox.Value;
            app.find_parameters = value;
            parameters = [app.CostSpinner, app.KSpinner, app.GammaSpinner];
            
            if (value == 1)
               set(parameters, 'Enable', 'off');
            elseif (value == 0)
                if isequal(app.classifier_method, 'KNN')
                    set(app.KSpinner, 'Enable', 'on');
                elseif isequal(app.classifier_method, 'SVM Linear')
                    set(app.CostSpinner, 'Enable', 'on');
                elseif isequal(app.classifier_method, 'SVM RBF')
                    set(app.CostSpinner, 'Enable', 'on');
                    set(app.GammaSpinner, 'Enable', 'on');
                end
            end
            
        end

        % Value changed function: CostSpinner
        function CostSpinnerValueChanged(app, event)
            value = app.CostSpinner.Value;
            app.cost = value;
        end

        % Value changed function: KSpinner
        function KSpinnerValueChanged(app, event)
            value = app.KSpinner.Value;
            app.k = value;
        end

        % Value changed function: GammaSpinner
        function GammaSpinnerValueChanged(app, event)
            value = app.GammaSpinner.Value;
            app.gamma = value;
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_4.SelectedObject;
            app.test_option = selectedButton.Text;
            
            if (selectedButton == app.CrossValidationButton_2)
                set(app.KSpinner_4, 'Enable', 'on');
                set(app.TrainingPercentageSpinner, 'Enable', 'off');
            elseif (selectedButton == app.HoldoutButton_2)
                set(app.KSpinner_4, 'Enable', 'off');
                set(app.TrainingPercentageSpinner, 'Enable', 'on');
            end
        end

        % Value changed function: KSpinner_4
        function KSpinner_4ValueChanged(app, event)
            value = app.KSpinner_4.Value;
            app.k_evaluation = value;
        end

        % Value changed function: TrainingPercentageSpinner
        function TrainingPercentageSpinnerValueChanged(app, event)
            value = app.TrainingPercentageSpinner.Value;
            app.training_percentage = value/100;
        end

        % Value changed function: ShowplotsCheckBox
        function ShowplotsCheckBoxValueChanged(app, event)
            value = app.ShowplotsCheckBox.Value;
            app.show_plots = value;
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
        metrics = ...
            main(app.scenario, app.selection_method, app.show_heatmap, app.n_features, app.corr_threshold, ...
                    app.reduction_method, app.dim, app.kaiser_criterion, app.show_pareto, app.show_eigenvalues, ...
                    app.show_reduced_data, app.classifier_method, app.k, app.cost, app.gamma, app.find_parameters, ...
                    app.test_option, app.k_evaluation, app.training_percentage, app.show_plots);
           
        app.accuracy = metrics.accuracy;
        app.accuracy_std = metrics.accuracy_std; 
        app.sensitivity = metrics.sensitivity;
        app.sensitivity_std = metrics.sensitivity_std; 
        app.specificity = metrics.specificity;   
        app.specificity_std = metrics.specificity_std; 
        app.mathews_coef = metrics.mcc; 
        app.mathews_coef_std = metrics.mcc_std;
        app.fscore = metrics.f_score;
        app.fscore_std = metrics.f_score_std;
        
        app.AccuracyEditField.Value = app.accuracy;
        app.AccuracyEditField_STD.Value = app.accuracy_std;
        app.SensitivityEditField.Value = app.sensitivity;
        app.SensitivityEditField_STD.Value = app.sensitivity_std;
        app.SpecificityEditField.Value = app.specificity;
        app.SpecificityEditField_STD.Value = app.specificity_std;
        app.MathewscoefficientEditField.Value = app.mathews_coef;
        app.MathewscoefficientEditField_STD.Value = app.mathews_coef_std;
        app.FScoreEditField.Value = app.fscore;
        app.FScoreEditField_STD.Value = app.fscore_std;
        end

        % Button pushed function: INFOButton
        function INFOButtonPushed(app, event)
            msgbox(["Scenario A (Binary Classifier): where one wants to distinguish the Released outcome from the " + ...
                "others using just patient information (PatientInfo.csv)"; "  ";
                "Scenario B (Binary Classifier): where one wants to distinguish the Deceased outcome from the" + ...
                "others using additional information as the weather;"; "  ";
                "Scenario C (Three-class Classifier): where one wants to distinguish Released, Isolated, and Deceased outcomes."],'Help', 'help');
            % SNN
        end

        % Callback function
        function ClassesbalancedCheckBoxValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_blue = uilabel(app.UIFigure);
            app.Label_blue.Text = '';
            app.Covid19predictionLabel = uilabel(app.UIFigure);
            app.Covid19predictionLabel.Text = 'Covid-19 prediction';
            app.PatternRecognition202021JoanaBaioJooFerreiraLabel = uilabel(app.UIFigure);
            app.PatternRecognition202021JoanaBaioJooFerreiraLabel.Text = 'Pattern Recognition - 2020/21 - Joana Baião (2017260526) & João Ferreira (2017246476)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_______________________________';
            app.ScenarioDropDownLabel = uilabel(app.UIFigure);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.UIFigure);
            app.ScenarioDropDown.Items = {'A', 'B', 'C'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Value = 'A';
            app.FeatureSelectionPanel = uipanel(app.UIFigure);
            app.FeatureSelectionPanel.Title = '1. Feature Selection';
            app.ButtonGroup = uibuttongroup(app.FeatureSelectionPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.KruskalWallisButton = uiradiobutton(app.ButtonGroup);
            app.KruskalWallisButton.Text = 'Kruskal-Wallis';
            app.KruskalWallisButton.Value = true;
            app.CorrelationButton = uiradiobutton(app.ButtonGroup);
            app.CorrelationButton.Text = 'Correlation';
            app.KruskalWallisCorrelationButton = uiradiobutton(app.ButtonGroup);
            app.KruskalWallisCorrelationButton.Text = 'Kruskal-Wallis + Correlation';
            app.ParametersPanel_2 = uipanel(app.FeatureSelectionPanel);
            app.ParametersPanel_2.Title = 'Parameters';
            app.NooffeaturesSpinnerLabel = uilabel(app.ParametersPanel_2);
            app.NooffeaturesSpinnerLabel.Text = 'No of features';
            app.NooffeaturesSpinner = uispinner(app.ParametersPanel_2);
            app.NooffeaturesSpinner.ValueChangedFcn = createCallbackFcn(app, @NooffeaturesSpinnerValueChanged, true);
            app.CorrelationThresholdLabel = uilabel(app.ParametersPanel_2);
            app.CorrelationThresholdLabel.Text = {'Correlation'; 'Threshold'};
            app.CorrelationThresholdSpinner = uispinner(app.ParametersPanel_2);
            app.CorrelationThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @CorrelationThresholdSpinnerValueChanged, true);
            app.CorrelationThresholdSpinner.Enable = 'off';
            app.ShowheatmapCheckBox = uicheckbox(app.FeatureSelectionPanel);
            app.ShowheatmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowheatmapCheckBoxValueChanged, true);
            app.ShowheatmapCheckBox.Enable = 'off';
            app.ShowheatmapCheckBox.Text = 'Show heat map';
            app.FeatureReductionPanel = uipanel(app.UIFigure);
            app.FeatureReductionPanel.Title = '2. Feature Reduction';
            app.ButtonGroup_2 = uibuttongroup(app.FeatureReductionPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.PCAButton = uiradiobutton(app.ButtonGroup_2);
            app.PCAButton.Text = 'PCA';
            app.PCAButton.Value = true;
            app.LDAButton = uiradiobutton(app.ButtonGroup_2);
            app.LDAButton.Text = 'LDA';
            app.ParametersPanel_3 = uipanel(app.FeatureReductionPanel);
            app.ParametersPanel_3.Title = 'Parameters';
            app.NoofdimensionsSpinnerLabel = uilabel(app.ParametersPanel_3);
            app.NoofdimensionsSpinnerLabel.Text = {'No of '; 'dimensions'};
            app.NoofdimensionsSpinner = uispinner(app.ParametersPanel_3);
            app.NoofdimensionsSpinner.Limits = [0 Inf];
            app.NoofdimensionsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofdimensionsSpinnerValueChanged, true);
            app.KaiserCriterionCheckBox = uicheckbox(app.ParametersPanel_3);
            app.KaiserCriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @KaiserCriterionCheckBoxValueChanged, true);
            app.KaiserCriterionCheckBox.Text = 'Kaiser Criterion';
            app.PlotEigenvaluesCheckBox = uicheckbox(app.FeatureReductionPanel);
            app.PlotEigenvaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotEigenvaluesCheckBoxValueChanged, true);
            app.PlotEigenvaluesCheckBox.Text = 'Plot Eigenvalues';
            app.PlotParetoCheckBox = uicheckbox(app.FeatureReductionPanel);
            app.PlotParetoCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotParetoCheckBoxValueChanged, true);
            app.PlotParetoCheckBox.Text = 'Plot Pareto';
            app.PlotReduceddataCheckBox = uicheckbox(app.FeatureReductionPanel);
            app.PlotReduceddataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotReduceddataCheckBoxValueChanged, true);
            app.PlotReduceddataCheckBox.Text = 'Plot Reduced data';
            app.ClassifierPanel = uipanel(app.UIFigure);
            app.ClassifierPanel.Title = '3. Classifier';
            app.ButtonGroup_3 = uibuttongroup(app.ClassifierPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.MDCEuclideanButton = uiradiobutton(app.ButtonGroup_3);
            app.MDCEuclideanButton.Text = 'MDC - Euclidean';
            app.MDCEuclideanButton.Value = true;
            app.MDCMahalanobisButton = uiradiobutton(app.ButtonGroup_3);
            app.MDCMahalanobisButton.Text = 'MDC - Mahalanobis';
            app.FisherLDAButton = uiradiobutton(app.ButtonGroup_3);
            app.FisherLDAButton.Text = 'Fisher LDA';
            app.SVMLinearButton = uiradiobutton(app.ButtonGroup_3);
            app.SVMLinearButton.Text = 'SVM Linear';
            app.SVMRBFButton = uiradiobutton(app.ButtonGroup_3);
            app.SVMRBFButton.Text = 'SVM RBF';
            app.BayesButton = uiradiobutton(app.ButtonGroup_3);
            app.BayesButton.Text = 'Bayes';
            app.KNNButton = uiradiobutton(app.ButtonGroup_3);
            app.KNNButton.Text = 'KNN';
            app.ParametersPanel = uipanel(app.ClassifierPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.FindappropriateparametersCheckBox = uicheckbox(app.ParametersPanel);
            app.FindappropriateparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @FindappropriateparametersCheckBoxValueChanged, true);
            app.FindappropriateparametersCheckBox.Enable = 'off';
            app.FindappropriateparametersCheckBox.Text = 'Find appropriate parameters';
            app.CostSpinnerLabel = uilabel(app.ParametersPanel);
            app.CostSpinnerLabel.Text = 'Cost';
            app.CostSpinner = uispinner(app.ParametersPanel);
            app.CostSpinner.ValueChangedFcn = createCallbackFcn(app, @CostSpinnerValueChanged, true);
            app.CostSpinner.Enable = 'off';
            app.KSpinnerLabel = uilabel(app.ParametersPanel);
            app.KSpinnerLabel.Text = 'K';
            app.KSpinner = uispinner(app.ParametersPanel);
            app.KSpinner.ValueChangedFcn = createCallbackFcn(app, @KSpinnerValueChanged, true);
            app.KSpinner.Enable = 'off';
            app.GammaSpinnerLabel = uilabel(app.ParametersPanel);
            app.GammaSpinnerLabel.Text = 'Gamma';
            app.GammaSpinner = uispinner(app.ParametersPanel);
            app.GammaSpinner.ValueChangedFcn = createCallbackFcn(app, @GammaSpinnerValueChanged, true);
            app.GammaSpinner.Enable = 'off';
            app.ClassifierEvaluationPanel = uipanel(app.UIFigure);
            app.ClassifierEvaluationPanel.Title = '4. Classifier Evaluation';
            app.ButtonGroup_4 = uibuttongroup(app.ClassifierEvaluationPanel);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.CrossValidationButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.CrossValidationButton_2.Text = 'Cross-Validation';
            app.CrossValidationButton_2.Value = true;
            app.HoldoutButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.HoldoutButton_2.Text = 'Holdout';
            app.ParametersPanel_4 = uipanel(app.ClassifierEvaluationPanel);
            app.ParametersPanel_4.Title = 'Parameters';
            app.TrainingPercentageSpinnerLabel = uilabel(app.ParametersPanel_4);
            app.TrainingPercentageSpinnerLabel.Text = {'Training '; 'Percentage'};
            app.TrainingPercentageSpinner = uispinner(app.ParametersPanel_4);
            app.TrainingPercentageSpinner.Limits = [0 100];
            app.TrainingPercentageSpinner.ValueChangedFcn = createCallbackFcn(app, @TrainingPercentageSpinnerValueChanged, true);
            app.TrainingPercentageSpinner.Enable = 'off';
            app.KSpinner_4Label = uilabel(app.ParametersPanel_4);
            app.KSpinner_4Label.Text = 'K';
            app.KSpinner_4 = uispinner(app.ParametersPanel_4);
            app.KSpinner_4.Limits = [0 Inf];
            app.KSpinner_4.ValueChangedFcn = createCallbackFcn(app, @KSpinner_4ValueChanged, true);
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            app.RESULTSPanel = uipanel(app.UIFigure);
            app.RESULTSPanel.Title = 'RESULTS';
            app.AccuracyEditFieldLabel = uilabel(app.RESULTSPanel);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_2Label = uilabel(app.RESULTSPanel);
            app.EditField_2Label.Text = '±';
            app.AccuracyEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.SensivityEditFieldLabel = uilabel(app.RESULTSPanel);
            app.SensivityEditFieldLabel.Text = 'Sensivity';
            app.SensitivityEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.SensitivityEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_3Label = uilabel(app.RESULTSPanel);
            app.EditField_3Label.Text = '±';
            app.SpecificityEditFieldLabel = uilabel(app.RESULTSPanel);
            app.SpecificityEditFieldLabel.Text = 'Specificity';
            app.SpecificityEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.SpecificityEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_3Label_2 = uilabel(app.RESULTSPanel);
            app.EditField_3Label_2.Text = '±';
            app.MathewscoefficientEditFieldLabel = uilabel(app.RESULTSPanel);
            app.MathewscoefficientEditFieldLabel.Text = {'Mathews '; 'coefficient'};
            app.MathewscoefficientEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.MathewscoefficientEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_3Label_3 = uilabel(app.RESULTSPanel);
            app.EditField_3Label_3.Text = '±';
            app.FScoreEditFieldLabel = uilabel(app.RESULTSPanel);
            app.FScoreEditFieldLabel.Text = 'F-Score';
            app.FScoreEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.FScoreEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_3Label_4 = uilabel(app.RESULTSPanel);
            app.EditField_3Label_4.Text = '±';
            app.ShowplotsCheckBox = uicheckbox(app.UIFigure);
            app.ShowplotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowplotsCheckBoxValueChanged, true);
            app.ShowplotsCheckBox.Text = 'Show plots';
            app.INFOButton = uibutton(app.UIFigure, 'push');
            app.INFOButton.ButtonPushedFcn = createCallbackFcn(app, @INFOButtonPushed, true);
            app.INFOButton.Text = '+ INFO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end