classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NTrainSamplesSliderLabel_2      matlab.ui.control.Label
        nSamplesSpinner                 matlab.ui.control.Spinner
        Label_3                         matlab.ui.control.Label
        FranciscoFernandesJooMarcelinoLabel  matlab.ui.control.Label
        NIterationsDropDown             matlab.ui.control.DropDown
        NIterationsLabel                matlab.ui.control.Label
        TestButton                      matlab.ui.control.Button
        RESULTSPanel                    matlab.ui.container.Panel
        EditField_3Label_4              matlab.ui.control.Label
        FScoreEditField_STD             matlab.ui.control.NumericEditField
        FScoreEditField                 matlab.ui.control.NumericEditField
        FScoreEditFieldLabel            matlab.ui.control.Label
        EditField_3Label_2              matlab.ui.control.Label
        SpecificityEditField_STD        matlab.ui.control.NumericEditField
        SpecificityEditField            matlab.ui.control.NumericEditField
        SpecificityEditFieldLabel       matlab.ui.control.Label
        EditField_3Label                matlab.ui.control.Label
        SensitivityEditField_STD        matlab.ui.control.NumericEditField
        SensitivityEditField            matlab.ui.control.NumericEditField
        SensitivityLabel                matlab.ui.control.Label
        AccuracyEditField_STD           matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        AccuracyEditField               matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel          matlab.ui.control.Label
        ModelSelectionPanel             matlab.ui.container.Panel
        BayesMisscalssCostRatioDropDown  matlab.ui.control.DropDown
        BayesMisscalssCostRatioDropDownLabel  matlab.ui.control.Label
        ModelLabel                      matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        FeatureSelectionReductionPanel  matlab.ui.container.Panel
        MethodLabel                     matlab.ui.control.Label
        NumberFeaturesDropDown          matlab.ui.control.DropDown
        PCAFeaturesLabel                matlab.ui.control.Label
        FeaturesDropDown                matlab.ui.control.DropDown
        HeartDiseasesPatternRecognition2022Label  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            nSamples = app.nSamplesSpinner.Value;
            scenario = app.ScenarioDropDown.Value;
            features = app.FeaturesDropDown.Value;
            n_features = app.NumberFeaturesDropDown.Value;
            model = app.ModelDropDown.Value;
            n_iterations = app.NIterationsDropDown.Value;
            cost = app.BayesMisscalssCostRatioDropDown.Value;

            [Accuracy, Accuracy_std , Sensitivity, Sensitivity_std, Specificity, Specificity_std, FScore, FScore_std] = guiTest(scenario, features, n_features, model, n_iterations, cost,nSamples);
               
            app.AccuracyEditField.Value = Accuracy;
            app.AccuracyEditField_STD.Value = Accuracy_std;

            if isnan(Specificity) == false
                app.SpecificityEditField.Value = Specificity;
                app.SpecificityEditField_STD.Value = Specificity_std;
            end
            if isnan(Sensitivity) == false
                app.SensitivityEditField.Value = Sensitivity;
                app.SensitivityEditField_STD.Value = Sensitivity_std;
            end
            if isnan(FScore) == false
                app.FScoreEditField.Value = FScore;
                app.FScoreEditField_STD.Value = FScore_std;
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScenarioDropDownLabel = uilabel(app.UIFigure);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.UIFigure);
            app.ScenarioDropDown.Items = {'1', '2'};
            app.ScenarioDropDown.Value = '1';
            app.HeartDiseasesPatternRecognition2022Label = uilabel(app.UIFigure);
            app.HeartDiseasesPatternRecognition2022Label.Text = {'Heart Diseases'; 'Pattern Recognition 2022'};
            app.FeatureSelectionReductionPanel = uipanel(app.UIFigure);
            app.FeatureSelectionReductionPanel.Title = '1. Feature Selection/Reduction';
            app.FeaturesDropDown = uidropdown(app.FeatureSelectionReductionPanel);
            app.FeaturesDropDown.Items = {'Raw', 'PCA', 'Kruskal-Wallis', 'Fishers LD', 'ROC Curve'};
            app.FeaturesDropDown.Value = 'PCA';
            app.PCAFeaturesLabel = uilabel(app.FeatureSelectionReductionPanel);
            app.PCAFeaturesLabel.Text = {'PCA '; 'Features'};
            app.NumberFeaturesDropDown = uidropdown(app.FeatureSelectionReductionPanel);
            app.NumberFeaturesDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.NumberFeaturesDropDown.Value = '1';
            app.MethodLabel = uilabel(app.FeatureSelectionReductionPanel);
            app.MethodLabel.Text = 'Method';
            app.ModelSelectionPanel = uipanel(app.UIFigure);
            app.ModelSelectionPanel.Title = '2. Model Selection';
            app.ModelDropDown = uidropdown(app.ModelSelectionPanel);
            app.ModelDropDown.Items = {'Linear MDC', 'Mahal MDC', 'Fishers LD', 'Bayes Classifier', 'KNN Classifier', 'SVMs'};
            app.ModelDropDown.Value = 'Linear MDC';
            app.ModelLabel = uilabel(app.ModelSelectionPanel);
            app.ModelLabel.Text = 'Model';
            app.BayesMisscalssCostRatioDropDownLabel = uilabel(app.ModelSelectionPanel);
            app.BayesMisscalssCostRatioDropDownLabel.Text = {'Bayes'; 'Misscalss. Cost Ratio'};
            app.BayesMisscalssCostRatioDropDown = uidropdown(app.ModelSelectionPanel);
            app.BayesMisscalssCostRatioDropDown.Items = {'1', '5', '10', '20', '40'};
            app.BayesMisscalssCostRatioDropDown.Value = '1';
            app.RESULTSPanel = uipanel(app.UIFigure);
            app.RESULTSPanel.Title = 'RESULTS';
            app.AccuracyEditFieldLabel = uilabel(app.RESULTSPanel);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_2Label = uilabel(app.RESULTSPanel);
            app.EditField_2Label.Text = '±';
            app.AccuracyEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.AccuracyEditField_STD.ValueDisplayFormat = '%5.4f';
            app.SensitivityLabel = uilabel(app.RESULTSPanel);
            app.SensitivityLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.SensitivityEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.SensitivityEditField_STD.ValueDisplayFormat = '%5.4f';
            app.EditField_3Label = uilabel(app.RESULTSPanel);
            app.EditField_3Label.Text = '±';
            app.SpecificityEditFieldLabel = uilabel(app.RESULTSPanel);
            app.SpecificityEditFieldLabel.Text = 'Specificity';
            app.SpecificityEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.SpecificityEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.SpecificityEditField_STD.ValueDisplayFormat = '%5.4f';
            app.EditField_3Label_2 = uilabel(app.RESULTSPanel);
            app.EditField_3Label_2.Text = '±';
            app.FScoreEditFieldLabel = uilabel(app.RESULTSPanel);
            app.FScoreEditFieldLabel.Text = 'F-Score';
            app.FScoreEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.FScoreEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.FScoreEditField_STD.ValueDisplayFormat = '%5.4f';
            app.EditField_3Label_4 = uilabel(app.RESULTSPanel);
            app.EditField_3Label_4.Text = '±';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.NIterationsLabel = uilabel(app.UIFigure);
            app.NIterationsLabel.Text = 'N Iterations';
            app.NIterationsDropDown = uidropdown(app.UIFigure);
            app.NIterationsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.NIterationsDropDown.Value = '1';
            app.FranciscoFernandesJooMarcelinoLabel = uilabel(app.UIFigure);
            app.FranciscoFernandesJooMarcelinoLabel.Text = {'Francisco Fernandes '; 'João Marcelino           '};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'2018278239'; '2018279700'};
            app.nSamplesSpinner = uispinner(app.UIFigure);
            app.nSamplesSpinner.Step = 1000;
            app.nSamplesSpinner.Limits = [100 200000];
            app.nSamplesSpinner.ValueDisplayFormat = '%.0f';
            app.nSamplesSpinner.Value = 200000;
            app.NTrainSamplesSliderLabel_2 = uilabel(app.UIFigure);
            app.NTrainSamplesSliderLabel_2.Text = {'N Train'; 'Samples'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end